classdef Electrode_Analyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ElectrodeAnalyser             matlab.ui.Figure
        Tab                           matlab.ui.container.TabGroup
        ImpedanceSpectrometryTab      matlab.ui.container.Tab
        SelectedfilesTextAreaLabel    matlab.ui.control.Label
        filesListEIS                  matlab.ui.control.TextArea
        BrowseButtonEIS               matlab.ui.control.Button
        UIModulus                     matlab.ui.control.UIAxes
        UIPhase                       matlab.ui.control.UIAxes
        tableImpedance1khz            matlab.ui.control.Table
        ProcessButtonEIS              matlab.ui.control.Button
        CyclicVoltametryTab           matlab.ui.container.Tab
        BrowseButtonCV                matlab.ui.control.Button
        figureCV                      matlab.ui.control.UIAxes
        figureCV2                     matlab.ui.control.UIAxes
        tableCSC                      matlab.ui.control.Table
        ProcessButtonCV               matlab.ui.control.Button
        SelectedfilesTextArea_2Label  matlab.ui.control.Label
        filesListCV                   matlab.ui.control.TextArea
        ShowDropDownLabel             matlab.ui.control.Label
        filesDropDown                 matlab.ui.control.DropDown
        VoltageTransientTab           matlab.ui.container.Tab
    end


    properties (Access = public)
        importedFilesEIS % string or cell array containing the .DTA files to process
        loopSizeEIS % number of imported files
        fileNameArrayEIS
        implantIDArrayEIS
        electrodeIDArrayEIS
        frequency
        zModulusArray
        zPhaseArray
        implantModulusPlot
        implantPhasePlot
        
        importedFilesCV % string or cell array containing the .DTA files to process
        loopSizeCV % number of imported files
        fileNameArrayCV
        implantIDArrayCV
        electrodeIDArrayCV
        timeArray
        voltageArray
        currentArray
        CVPlot
        phase1Edgesjx2xn
        phase2Edgesjx2xn
        phase3Edgesjx2xn
        phase4Edgesjx2xn
        voltageArrayjx2xn
        currentArrayjx2xn
        timeArrayjx2xn
        
        
    end

    methods (Access = private)
        
        function importFrequency = importFreq(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   FREQUENCY = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   FREQUENCY = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   frequency = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:07:58
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column4: text (%s)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%s%*s%*s%*s%*s%*s%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importFrequency = dataArray{:, 1};
        end
        
        function importZMod = importMod(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   VARNAME8 = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   VARNAME8 = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   VarName8 = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:09:28
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column8: double (%f)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%*s%*s%*s%*s%f%*s%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importZMod = dataArray{:, 1};
        end

        function importZPhz = importPhz(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as column vectors.
            %   VARNAME9 = IMPORTFILE(FILENAME) Reads data from text file FILENAME for
            %   the default selection.
            %
            %   VARNAME9 = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads data from rows
            %   STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   VarName9 = importfile('170914_CI001_2C_EIS.DTA',80, 130);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/15 16:10:36
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 80;
                endRow = inf;
            end
            
            %% Format for each line of text:
            %   column9: double (%f)
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%*s%*s%*s%*s%*s%f%*s%*s%*s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Post processing for unimportable data.
            % No unimportable data rules were applied during the import, so no post
            % processing code is included. To generate code which works for
            % unimportable data, select unimportable cells in a file and regenerate the
            % script.
            
            %% Allocate imported array to column variable names
            importZPhz = dataArray{:, 1};
        end  
        
        function timeArray = importTime(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as a matrix.
            %   PAAMPEDOTW002ID00107CV = IMPORTFILE(FILENAME) Reads data from text file
            %   FILENAME for the default selection.
            %
            %   PAAMPEDOTW002ID00107CV = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads
            %   data from rows STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   PAAmPEDOTW002ID00107CV = importfile('170826_PAAm_PEDOT_W002_ID001_07_CV.DTA', 63, 4275);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/20 16:11:35
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 63;
                endRow = inf;
            end
            
            %% Read columns of data as text:
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Convert the contents of columns containing numeric text to numbers.
            % Replace non-numeric text with NaN.
            raw = repmat({''},length(dataArray{1}),length(dataArray)-1);
            for col=1:length(dataArray)-1
                raw(1:length(dataArray{col}),col) = mat2cell(dataArray{col}, ones(length(dataArray{col}), 1));
            end
            numericData = NaN(size(dataArray{1},1),size(dataArray,2));
            
            % Converts text in the input cell array to numbers. Replaced non-numeric
            % text with NaN.
            rawData = dataArray{1};
            for row=1:size(rawData, 1)
                % Create a regular expression to detect and remove non-numeric prefixes and
                % suffixes.
                regexstr = '(?<prefix>.*?)(?<numbers>([-]*(\d+[\,]*)+[\.]{0,1}\d*[eEdD]{0,1}[-+]*\d*[i]{0,1})|([-]*(\d+[\,]*)*[\.]{1,1}\d+[eEdD]{0,1}[-+]*\d*[i]{0,1}))(?<suffix>.*)';
                try
                    result = regexp(rawData(row), regexstr, 'names');
                    numbers = result.numbers;
                    
                    % Detected commas in non-thousand locations.
                    invalidThousandsSeparator = false;
                    if numbers.contains(',')
                        thousandsRegExp = '^\d+?(\,\d{3})*\.{0,1}\d*$';
                        if isempty(regexp(numbers, thousandsRegExp, 'once'))
                            numbers = NaN;
                            invalidThousandsSeparator = true;
                        end
                    end
                    % Convert numeric text to numbers.
                    if ~invalidThousandsSeparator
                        numbers = textscan(char(strrep(numbers, ',', '')), '%f');
                        numericData(row, 1) = numbers{1};
                        raw{row, 1} = numbers{1};
                    end
                catch
                    raw{row, 1} = rawData{row};
                end
            end
            
            
            %% Replace non-numeric cells with NaN
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {NaN}; % Replace non-numeric cells
            
            %% Create output variable
            timeArray = cell2mat(raw);
        end
        
        function voltageArray = importVoltage(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as a matrix.
            %   SPPEDOTW001ID05006CV = IMPORTFILE(FILENAME) Reads data from text file
            %   FILENAME for the default selection.
            %
            %   SPPEDOTW001ID05006CV = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads
            %   data from rows STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   SPPEDOTW001ID05006CV = importfile('170825_SP_PEDOT_W001_ID050_06_CV.DTA', 63, 4275);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/20 13:20:32
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 63;
                endRow = inf;
            end
            
            %% Read columns of data as text:
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Convert the contents of columns containing numeric text to numbers.
            % Replace non-numeric text with NaN.
            raw = repmat({''},length(dataArray{1}),length(dataArray)-1);
            for col=1:length(dataArray)-1
                raw(1:length(dataArray{col}),col) = mat2cell(dataArray{col}, ones(length(dataArray{col}), 1));
            end
            numericData = NaN(size(dataArray{1},1),size(dataArray,2));
            
            % Converts text in the input cell array to numbers. Replaced non-numeric
            % text with NaN.
            rawData = dataArray{1};
            for row=1:size(rawData, 1)
                % Create a regular expression to detect and remove non-numeric prefixes and
                % suffixes.
                regexstr = '(?<prefix>.*?)(?<numbers>([-]*(\d+[\,]*)+[\.]{0,1}\d*[eEdD]{0,1}[-+]*\d*[i]{0,1})|([-]*(\d+[\,]*)*[\.]{1,1}\d+[eEdD]{0,1}[-+]*\d*[i]{0,1}))(?<suffix>.*)';
                try
                    result = regexp(rawData(row), regexstr, 'names');
                    numbers = result.numbers;
                    
                    % Detected commas in non-thousand locations.
                    invalidThousandsSeparator = false;
                    if numbers.contains(',')
                        thousandsRegExp = '^\d+?(\,\d{3})*\.{0,1}\d*$';
                        if isempty(regexp(numbers, thousandsRegExp, 'once'))
                            numbers = NaN;
                            invalidThousandsSeparator = true;
                        end
                    end
                    % Convert numeric text to numbers.
                    if ~invalidThousandsSeparator
                        numbers = textscan(char(strrep(numbers, ',', '')), '%f');
                        numericData(row, 1) = numbers{1};
                        raw{row, 1} = numbers{1};
                    end
                catch
                    raw{row, 1} = rawData{row};
                end
            end
            
            
            %% Replace non-numeric cells with NaN
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {NaN}; % Replace non-numeric cells
            
            %% Create output variable
            voltageArray = cell2mat(raw);
        end
        
        function currentArray = importCurrent(app,filename, startRow, endRow)
            %IMPORTFILE Import numeric data from a text file as a matrix.
            %   PAAMPEDOTW002ID00107CV = IMPORTFILE(FILENAME) Reads data from text file
            %   FILENAME for the default selection.
            %
            %   PAAMPEDOTW002ID00107CV = IMPORTFILE(FILENAME, STARTROW, ENDROW) Reads
            %   data from rows STARTROW through ENDROW of text file FILENAME.
            %
            % Example:
            %   PAAmPEDOTW002ID00107CV = importfile('170826_PAAm_PEDOT_W002_ID001_07_CV.DTA', 63, 4275);
            %
            %    See also TEXTSCAN.
            
            % Auto-generated by MATLAB on 2017/09/20 14:04:09
            
            %% Initialize variables.
            delimiter = '\t';
            if nargin<=2
                startRow = 63;
                endRow = inf;
            end
            
            %% Read columns of data as text:
            % For more information, see the TEXTSCAN documentation.
            formatSpec = '%*s%*s%*s%*s%s%[^\n\r]';
            
            %% Open the text file.
            fileID = fopen(filename,'r');
            
            %% Read columns of data according to the format.
            % This call is based on the structure of the file used to generate this
            % code. If an error occurs for a different file, try regenerating the code
            % from the Import Tool.
            dataArray = textscan(fileID, formatSpec, endRow(1)-startRow(1)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(1)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
            for block=2:length(startRow)
                frewind(fileID);
                dataArrayBlock = textscan(fileID, formatSpec, endRow(block)-startRow(block)+1, 'Delimiter', delimiter, 'TextType', 'string', 'HeaderLines', startRow(block)-1, 'ReturnOnError', false, 'EndOfLine', '\r\n');
                dataArray{1} = [dataArray{1};dataArrayBlock{1}];
            end
            
            %% Close the text file.
            fclose(fileID);
            
            %% Convert the contents of columns containing numeric text to numbers.
            % Replace non-numeric text with NaN.
            raw = repmat({''},length(dataArray{1}),length(dataArray)-1);
            for col=1:length(dataArray)-1
                raw(1:length(dataArray{col}),col) = mat2cell(dataArray{col}, ones(length(dataArray{col}), 1));
            end
            numericData = NaN(size(dataArray{1},1),size(dataArray,2));
            
            % Converts text in the input cell array to numbers. Replaced non-numeric
            % text with NaN.
            rawData = dataArray{1};
            for row=1:size(rawData, 1)
                % Create a regular expression to detect and remove non-numeric prefixes and
                % suffixes.
                regexstr = '(?<prefix>.*?)(?<numbers>([-]*(\d+[\,]*)+[\.]{0,1}\d*[eEdD]{0,1}[-+]*\d*[i]{0,1})|([-]*(\d+[\,]*)*[\.]{1,1}\d+[eEdD]{0,1}[-+]*\d*[i]{0,1}))(?<suffix>.*)';
                try
                    result = regexp(rawData(row), regexstr, 'names');
                    numbers = result.numbers;
                    
                    % Detected commas in non-thousand locations.
                    invalidThousandsSeparator = false;
                    if numbers.contains(',')
                        thousandsRegExp = '^\d+?(\,\d{3})*\.{0,1}\d*$';
                        if isempty(regexp(numbers, thousandsRegExp, 'once'))
                            numbers = NaN;
                            invalidThousandsSeparator = true;
                        end
                    end
                    % Convert numeric text to numbers.
                    if ~invalidThousandsSeparator
                        numbers = textscan(char(strrep(numbers, ',', '')), '%f');
                        numericData(row, 1) = numbers{1};
                        raw{row, 1} = numbers{1};
                    end
                catch
                    raw{row, 1} = rawData{row};
                end
            end
            
            
            %% Replace non-numeric cells with NaN
            R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),raw); % Find non-numeric cells
            raw(R) = {NaN}; % Replace non-numeric cells
            
            %% Create output variable
            currentArray = cell2mat(raw);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: BrowseButtonCV
        function BrowseButtonCVPushed(app, event)
            % Load the names of the selected files
            app.ElectrodeAnalyser.Visible = 'off';
            disp('> Select the .DTA files...')
            [app.importedFilesCV, pathName] = uigetfile({'*.DTA'},'MultiSelect','on');
            drawnow;
            app.ElectrodeAnalyser.Visible = 'on';
            app.fileNameArrayCV=[];
            app.implantIDArrayCV=[];
            app.electrodeIDArrayCV=[];
            app.timeArray=[];
            app.voltageArray=[];
            app.currentArray=[];
            if iscell(app.importedFilesCV)
                app.loopSizeCV = size(app.importedFilesCV,2); %getfile has returned >1 files sorted in a cell array
            else
                app.loopSizeCV = 1; %getfile has return 1 file, fileNameArrayCV = 1xstringLength
            end
            for i=1:1:app.loopSizeCV
                if iscell(app.importedFilesCV)
                    app.fileNameArrayCV = cat(1,app.fileNameArrayCV, strjoin(app.importedFilesCV(i))); %strjoin converts cell type into string
                else
                    app.fileNameArrayCV = [app.importedFilesCV]; %getfile has only returned 1 file whose name is already a string
                end
            end
            app.filesListCV.Value = app.importedFilesCV;
            app.ProcessButtonCV.Enable = 'on';
        end

        % Button pushed function: BrowseButtonEIS
        function BrowseButtonEISPushed(app, event)
            % Load the names of the selected files
            app.ElectrodeAnalyser.Visible = 'off';
            disp('> Select the .DTA files...')
            [app.importedFilesEIS, pathName] = uigetfile({'*.DTA'},'MultiSelect','on');
            drawnow;
            app.ElectrodeAnalyser.Visible = 'on';
            app.fileNameArrayEIS=[];
            app.implantIDArrayEIS=[];
            app.electrodeIDArrayCV=[];
            app.frequency=[];
            app.zModulusArray=[];
            app.zPhaseArray=[];
            if iscell(app.importedFilesEIS)
                app.loopSizeEIS = size(app.importedFilesEIS,2); %getfile has returned >1 files sorted in a cell array
            else
                app.loopSizeEIS = 1; %getfile has return 1 file, fileNameArrayEIS = 1xstringLength
            end
            for i=1:1:app.loopSizeEIS
                if iscell(app.importedFilesEIS)
                    app.fileNameArrayEIS = cat(1,app.fileNameArrayEIS, strjoin(app.importedFilesEIS(i))); %strjoin converts cell type into string
                else
                    app.fileNameArrayEIS = [app.importedFilesEIS]; %getfile has only returned 1 file whose name is already a string
                end
            end
            app.filesListEIS.Value = app.importedFilesEIS;
            app.ProcessButtonEIS.Enable = 'on';
        end

        % Button pushed function: ProcessButtonCV
        function ProcessButtonCVPushed(app, event)
            
            app.ElectrodeAnalyser.Visible = 'off';
            for n=1:1:app.loopSizeCV
                
                % Extract the implant and electrode ID from the file name
                fileName = app.fileNameArrayCV(n,:);
                indexOfImplantID = strfind(fileName,'W');
                implantID = fileName(indexOfImplantID:indexOfImplantID+9);
                app.implantIDArrayCV = [app.implantIDArrayCV; implantID];
                electrodeID = fileName(indexOfImplantID+11:indexOfImplantID+12);
                app.electrodeIDArrayCV = [app.electrodeIDArrayCV; electrodeID];
                fprintf('> Processing electrode %s_%s...\n',implantID,electrodeID);
                
                % Import data from the DTA files
                disp('> Importing data')
                app.voltageArray = importVoltage(fileName);
                app.voltageArray = app.voltageArray(~isnan(app.voltageArray)); %remove NaN values
                app.currentArray = importCurrent(fileName);
                app.currentArray = app.currentArray(~isnan(app.currentArray));
                app.timeArray = importTime(fileName);
                app.timeArray = app.timeArray(~isnan(app.timeArray));
                
                % Initialize local variable
                chargeStorageCapacityArray = [];
                chargeStorageCapacityRow = [];
                chargeStorageCapacityTemp = 0;
                chargeStorageCapacity = [];
                phase1Edges = [];
                phase2Edges = [];
                phase3Edges = [];
                phase4Edges = [];
                curveNames = [];
                i = 2;
                
                % Sort data in 4 phases and compute charge storage capacity
                disp('> Sorting data')
                while i < size(app.voltageArray,1)
                    
                    % Phase 1: voltage positive, voltage slope positive
                    start = i;
                    while i < size(app.voltageArray,1) && app.voltageArray(i) > 0 && (app.voltageArray(i) - app.voltageArray(i-1)) > 0
                        chargeStorageCapacityTemp = chargeStorageCapacityTemp + abs(trapz(app.timeArray(i-1:i), app.currentArray(i-1:i))/(app.voltageArray(i)-app.voltageArray(i-1)));
                        i = i+1;
                    end
                    phase1Edges = [phase1Edges; start i-1];
                    chargeStorageCapacityRow  = [chargeStorageCapacityRow chargeStorageCapacityTemp];
                    chargeStorageCapacityTemp = 0;
                    
                    % Phase 2: voltage positive, voltage slope negative
                    start = i;
                    while i < size(app.voltageArray,1) && app.voltageArray(i) > 0 && (app.voltageArray(i) - app.voltageArray(i-1)) < 0
                        chargeStorageCapacityTemp = chargeStorageCapacityTemp + abs(trapz(app.timeArray(i-1:i), app.currentArray(i-1:i))/(app.voltageArray(i)-app.voltageArray(i-1)));
                        i = i+1;
                    end
                    phase2Edges = [phase2Edges; start i-1];
                    chargeStorageCapacityRow = [chargeStorageCapacityRow chargeStorageCapacityTemp];
                    chargeStorageCapacityTemp = 0;
                    
                    % Phase 3: voltage negative, voltage slope negative
                    start = i;
                    while i < size(app.voltageArray,1) && app.voltageArray(i) < 0 && (app.voltageArray(i) - app.voltageArray(i-1)) < 0
                        chargeStorageCapacityTemp = chargeStorageCapacityTemp + abs(trapz(app.timeArray(i-1:i), app.currentArray(i-1:i))/(app.voltageArray(i)-app.voltageArray(i-1)));
                        i = i+1;
                    end
                    phase3Edges = [phase3Edges; start i-1];
                    chargeStorageCapacityRow = [chargeStorageCapacityRow chargeStorageCapacityTemp];
                    chargeStorageCapacityTemp = 0;
                    
                    % Phase 4: voltage negative, voltage slope positive
                    start = i;
                    while i < size(app.voltageArray,1) && app.voltageArray(i) < 0 && (app.voltageArray(i) - app.voltageArray(i-1)) > 0
                        chargeStorageCapacityTemp = chargeStorageCapacityTemp + abs(trapz(app.timeArray(i-1:i), app.currentArray(i-1:i))/(app.voltageArray(i)-app.voltageArray(i-1)));
                        i = i+1;
                    end
                    phase4Edges = [phase4Edges; start i-1];
                    chargeStorageCapacityRow = [chargeStorageCapacityRow chargeStorageCapacityTemp];
                    chargeStorageCapacityTemp = 0;
                    
                    chargeStorageCapacityArray = [chargeStorageCapacityArray; chargeStorageCapacityRow];
                    chargeStorageCapacityRow = [];
                end
                % Save curves and their edges of each electrode
                app.voltageArrayjx2xn(:,:,n) = app.voltageArray;
                app.currentArrayjx2xn(:,:,n) = app.currentArray;
                app.timeArrayjx2xn(:,:,n) = app.timeArray;
                app.phase1Edgesjx2xn(:,:,n) = phase1Edges;
                app.phase2Edgesjx2xn(:,:,n) = phase2Edges;
                app.phase3Edgesjx2xn(:,:,n) = phase3Edges;
                app.phase4Edgesjx2xn(:,:,n) = phase4Edges;
                
                disp('> Computing and saving CSC to .txt file')
                for j=1:1:size(phase1Edges,1)
                    
                    % Generate and display individual CV plot
                    soloCVPlot = figure('Visible','off');
                    plot(app.voltageArray(phase1Edges(j,1):phase4Edges(j,2)), 10^6.*app.currentArray(phase1Edges(j,1):phase4Edges(j,2)),'LineWidth',2.5)
                    grid on
                    curveNames = [curveNames; replace(strcat('Curve_',num2str(j)),'_',' ')];
                    legend(curveNames(j,:))
                    xlabel('Voltage [V]')
                    ylabel('Current [\muA]')
                    title(replace(strcat('Cyclic Voltametry_',app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:)),'_',' '))
                    soloCVPlot = gcf;
                    soloCVPlot.PaperPositionMode = 'auto';
                    soloCVPlot_pos = soloCVPlot.PaperPosition;
                    soloCVPlot.PaperSize = [soloCVPlot_pos(3) soloCVPlot_pos(4)];
                    soloCVPlotFile = strcat(app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:),'_Curve_',num2str(j),'_CV_plot','.pdf');
                    saveas(gcf,soloCVPlotFile)
                    
                    %Populate global CV plot
                    app.CVPlot = figure('Visible','off');
                    plot(app.voltageArray(phase1Edges(j,1):phase4Edges(j,2)), 10^6.*app.currentArray(phase1Edges(j,1):phase4Edges(j,2)),'LineWidth',2.5)
                    hold on
                    
                    % Compute and save Charge Storage Capacity in .txt file
                    chargeStorageCapacity = [chargeStorageCapacity; sum(chargeStorageCapacityArray(j,:),2)];
                    if n == 1 && j == 1 % First curve of the first electrode is responsible for creating the file
                        fid = fopen(strcat(app.implantIDArrayCV(1,:),'_CSC','.txt'),'wt+');
                        fprintf(fid,'\t \t \t \t \t \t Charge Storage Capacity [mF]\n');
                        fprintf(fid, '%s_%s %s\t %f\t \n',implantID,electrodeID,curveNames(j,:),10^3.*chargeStorageCapacity(j));
                        app.tableCSC.Data = {electrodeID,curveNames(j,:),10^3.*chargeStorageCapacity(j)};
                    else
                        fid = fopen(strcat(app.implantIDArrayCV(1,:),'_CSC','.txt'),'at+');
                        fprintf(fid, '%s_%s %s\t %f\t \n',implantID,electrodeID,curveNames(j,:),10^3.*chargeStorageCapacity(j));
                        oldData = app.tableCSC.Data;
                        newData = cat(1,oldData, {electrodeID,curveNames(j,:),10^3.*chargeStorageCapacity(j)});
                        app.tableCSC.Data = newData;
                    end
                    if j == size(phase1Edges,1) % Last curve of each electrode is responsible for creating the avg and var
                        fid = fopen(strcat(app.implantIDArrayCV(1,:),'_CSC','.txt'),'at+');
                        fprintf(fid, '______________________________________________________________\n');
                        fprintf(fid, '%s_%s Average\t %f\t Variance\t %e\n',implantID,electrodeID,10^3*mean(sum(chargeStorageCapacityArray,2)),var(sum(chargeStorageCapacityArray,2)));
                        fprintf(fid, '______________________________________________________________\n');
                    end
                end
                curveNames
                disp('> Generating Plots')
                % Annotating global CV plot
                app.CVPlot = figure('Visible','off');
                grid on
                legend(curveNames)
                xlabel('Voltage [V]')
                ylabel('Current [\muA]')
                title(replace(strcat('Cyclic Voltametry_',app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:)),'_',' '))
                app.CVPlot = gcf;
                app.CVPlot.PaperPositionMode = 'auto';
                CVPlot_pos = app.CVPlot.PaperPosition;
                app.CVPlot.PaperSize = [CVPlot_pos(3) CVPlot_pos(4)];
                titleCVPlotFile = strcat(app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:),'_CV_plot','.pdf');
                saveas(gcf,titleCVPlotFile)
            
                %% GENERATE .xlsx FILE
                xlsxFile = strcat(app.implantIDArrayCV(1,:),'.xlsx');
                dataTable = table(app.timeArray, app.voltageArray, app.currentArray,'VariableNames',{'Time' 'Voltage' 'Current'});
                writetable(dataTable, xlsxFile, 'Sheet',app.electrodeIDArrayCV(n,:))
                
                disp('> Done.')
            end
            
            % Populate UIAxes with default implant (#1)
            hold(app.figureCV, 'on')
            hold(app.figureCV2,'on')
            defaultColors = [    0    0.4470    0.7410;
                0.8500    0.3250    0.0980;
                0.9290    0.6940    0.1250;
                0.4940    0.1840    0.5560;
                0.4660    0.6740    0.1880;
                0.3010    0.7450    0.9330;
                0.6350    0.0780    0.1840];
            for j=1:1:size(phase1Edges,1)
                % Current v Voltage
                plot(app.figureCV,app.voltageArray(phase1Edges(j,1):phase4Edges(j,2)), 10^6.*app.currentArray(phase1Edges(j,1):phase4Edges(j,2)),'LineWidth',2.5)
                % Current & Voltage v Time
                yyaxis(app.figureCV2,'right')
                plot(app.figureCV2,app.timeArray(phase1Edges(j,1):phase4Edges(j,2)),10^6.*app.currentArray(phase1Edges(j,1):phase4Edges(j,2)),'-','LineWidth',2.5,'Color',defaultColors(j,:))
            end
            grid(app.figureCV,'on')
            legend(app.figureCV,curveNames)
            xlabel(app.figureCV,'Voltage [V]')
            ylabel(app.figureCV,'Current [\muA]')
            title(app.figureCV,replace(strcat('Cyclic Voltametry_',app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:)),'_',' '))
            hold(app.figureCV, 'off')
            
            yyaxis(app.figureCV2,'left')
            app.figureCV2.YColor = 'black'
            xlabel(app.figureCV2,'Time [s]')
            ylabel(app.figureCV2,'Voltage [V]')
            plot(app.figureCV2,app.timeArray(phase1Edges(1,1):phase4Edges(size(phase1Edges,1),2)),app.voltageArray(phase1Edges(1,1):phase4Edges(size(phase1Edges,1),2)),'k','LineWidth',2.5)
            hold(app.figureCV2, 'off')
            yyaxis(app.figureCV2,'right')
            app.figureCV2.YColor = 'black'
            ylabel(app.figureCV2,'Current [\muA]')
            legend(app.figureCV2,['Voltage'; curveNames])
            title(app.figureCV2,replace(strcat('Cyclic Voltametry_',app.implantIDArrayCV(n,:),'_',app.electrodeIDArrayCV(n,:)),'_',' '))
            hold(app.figureCV2, 'off')
            
            % UI refresh
            app.ProcessButtonEIS.Enable = 'off';
            drawnow;
            app.ElectrodeAnalyser.Visible = 'off';
            app.ElectrodeAnalyser.Visible = 'on';
            if app.loopSizeCV ~= 1
                app.filesDropDown.Items = app.importedFilesCV;
            else
                app.filesDropDown.Items = {app.importedFilesCV}
            end
            app.filesDropDown.Value = app.filesDropDown.Items(1);
            app.filesDropDown.Enable = 'on';
            
        end

        % Button pushed function: ProcessButtonEIS
        function ProcessButtonEISPushed(app, event)
            
            app.ElectrodeAnalyser.Visible = 'off';
            for i=1:1:app.loopSizeEIS
                
                % Extract the implant and electrode ID from the file name
                fileName = app.fileNameArrayEIS(i,:);
                indexOfImplantID = strfind(fileName,'W');
                implantID = fileName(indexOfImplantID:indexOfImplantID+9);
                app.implantIDArrayEIS = [app.implantIDArrayEIS; implantID];
                electrodeID = fileName(indexOfImplantID+11:indexOfImplantID+12);
                app.electrodeIDArrayEIS = [app.electrodeIDArrayEIS; electrodeID];
                fprintf('> Processing electrode %s_%s...\n',implantID,electrodeID);
                
                % Import data from the DTA files
                disp('> Importing data')
                app.frequency = str2double(importFreq(app,fileName)); %frequency is stored as a string in data file
                zModulus = importMod(app,fileName);
                app.zModulusArray = [app.zModulusArray zModulus];
                zPhase = importPhz(app,fileName);
                app.zPhaseArray = [app.zPhaseArray zPhase];
                
                % Save impendance at 1kHz
                disp('> Populating .txt file')
                if i == 1
                    fid = fopen(strcat(app.implantIDArrayEIS(1,:),'_impedance_1kHz','.txt'),'wt+'); %create txt file
                    fprintf(fid,'\t \t Frequency [Hz]\t Impedance [kOhm]\n'); %init txt layout
                    fprintf(fid, '%s_%s\t %f.3\t %f.2\n',implantID,electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3);
                    app.tableImpedance1khz.Data = {electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3};
                else
                    fid = fopen(strcat(app.implantIDArrayEIS(1,:),'_impedance_1kHz','.txt'),'at+');
                    fprintf(fid, '%s_%s\t %f.3\t %f.2\n',implantID,electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640));
                    oldData = app.tableImpedance1khz.Data;
                    newData = cat(1,oldData, {electrodeID,app.frequency(app.frequency==998.2640),zModulus(app.frequency==998.2640)./10^3});
                    app.tableImpedance1khz.Data = newData;
                end
                
                % Generate Bode Plot
                disp('> Generating individual Bode plot')
                warning('off','MATLAB:print:FigureTooLargeForPage');
                titleModulusPlot = replace(strcat('Impedance Modulus_',implantID),'_',' '); %workaround because strcat ignores trailing white space
                titleModulusPlotFile = strcat(implantID,'_',electrodeID,'_impedance_modulus','.pdf');
                modulusPlot = figure('visible','off');
                semilogx(app.frequency,zModulus./10^3,'LineWidth',2.5)
                grid on
                legend(electrodeID)
                xlabel('Frequency [Hz]')
                ylabel('Modulus [k\Omega]')
                title(titleModulusPlot)
                modulusPlot = gcf;
                modulusPlot.PaperPositionMode = 'auto';
                modulusPlot_pos = modulusPlot.PaperPosition;
                modulusPlot.PaperSize = [modulusPlot_pos(3) modulusPlot_pos(4)];
                saveas(gcf,titleModulusPlotFile)
                
                titlePhasePlot = replace(strcat('Impedance Phase_',implantID),'_',' '); %workaround because strcat ignores trailing white space
                titlePhasePlotFile = strcat(implantID,'_',electrodeID,'_impedance_phase','.pdf');
                phasePlot = figure('visible','off');
                semilogx(app.frequency,zPhase,'LineWidth',2.5)
                grid on
                legend(electrodeID)
                xlabel('Frequency [Hz]')
                ylabel('Phase [deg]')
                title(titlePhasePlot)
                phasePlot = gcf;
                phasePlot.PaperPositionMode = 'auto';
                phasePlot_pos = phasePlot.PaperPosition;
                phasePlot.PaperSize = [phasePlot_pos(3) phasePlot_pos(4)];
                saveas(gcf,titlePhasePlotFile)
            
            end
            fclose(fid);
            
            %% GENERATE BODE PLOT FOR ENTIRE IMPLANT
            disp('> Generating full Bode plot')
            app.implantModulusPlot = figure('visible','off');
            grid on
            for i=1:1:app.loopSizeEIS
                semilogx(app.UIModulus,app.frequency,10^-3 * app.zModulusArray(:,i),'LineWidth',2.5)
                hold(app.UIModulus,'on')
                semilogx(app.frequency,app.zModulusArray(:,i)./10^3,'LineWidth',2.5)
                hold on
            end
            hold(app.UIModulus,'off')
            % UIaxes
            legend(app.UIModulus,app.electrodeIDArrayEIS)
            xlabel(app.UIModulus,'Frequency [Hz]')
            ylabel(app.UIModulus,'Modulus [k\Omega]')
            title(app.UIModulus,replace(strcat('Impedance Modulus_',app.implantIDArrayEIS(1,:)),'_',' '))
            %Saved figure
            grid on
            legend(app.electrodeIDArrayEIS)
            xlabel('Frequency [Hz]')
            ylabel('Modulus [k\Omega]')
            title(replace(strcat('Impedance Modulus_',app.implantIDArrayEIS(1,:)),'_',' '))
            app.implantModulusPlot.PaperPositionMode = 'auto';
            implantModulusPlot_pos = app.implantModulusPlot.PaperPosition;
            app.implantModulusPlot.PaperSize = [implantModulusPlot_pos(3) implantModulusPlot_pos(4)];
            titleImplantModulusPlotFile = strcat(app.implantIDArrayEIS(1,:),'_full_impedance_modulus','.pdf');
            saveas(gcf,titleImplantModulusPlotFile)
            
            app.implantPhasePlot = figure('visible','off');
            grid on
            for i=1:1:app.loopSizeEIS
                semilogx(app.UIPhase,app.frequency,app.zPhaseArray(:,i),'LineWidth',2.5)
                hold(app.UIPhase, 'on')
                semilogx(app.frequency,app.zPhaseArray(:,i),'LineWidth',2.5)
                hold on
            end
            hold(app.UIPhase,'off')
            % UIaxes
            legend(app.UIPhase,app.electrodeIDArrayEIS)
            xlabel(app.UIPhase,'Frequency [Hz]')
            ylabel(app.UIPhase,'Phase [deg]')
            title(app.UIPhase,replace(strcat('Impedance Phase_',app.implantIDArrayEIS(1,:)),'_',' '))
            %Saved figure
            grid on
            legend(app.electrodeIDArrayEIS)
            xlabel('Frequency [Hz]')
            ylabel('Phase [deg]')
            title(replace(strcat('Impedance Phase_',app.implantIDArrayEIS(1,:)),'_',' '))
            app.implantPhasePlot.PaperPositionMode = 'auto';
            implantPhasePlot_pos = app.implantPhasePlot.PaperPosition;
            app.implantPhasePlot.PaperSize = [implantPhasePlot_pos(3) implantPhasePlot_pos(4)];
            titleImplantPhasePlotFile = strcat(app.implantIDArrayEIS(1,:),'_full_impedance_phase','.pdf');
            saveas(gcf,titleImplantPhasePlotFile)
            
            % Generate xlsx file
            disp('> Populating xlsx file')
            warning('off','MATLAB:xlswrite:AddSheet');
            xlsxFile = strcat(app.implantIDArrayEIS(1,:),'.xlsx');
            for i=1:1:app.loopSizeEIS
                dataTable = table(app.frequency, app.zModulusArray(:,i), app.zPhaseArray(:,i),'VariableNames',{'Frequency' 'Modulus' 'Phase'});
                writetable(dataTable, xlsxFile, 'Sheet',app.electrodeIDArrayEIS(i,:));
            end
            
            disp('> Done.')
            % UI refresh
            app.ProcessButtonEIS.Enable = 'off';
            drawnow;
            app.ElectrodeAnalyser.Visible = 'off';
            app.ElectrodeAnalyser.Visible = 'on';
        end

        % Value changed function: filesDropDown
        function filesDropDownValueChanged(app, event)
            value = app.filesDropDown.Value;
            indexC = strfind(app.filesDropDown.Items, value); %returns a cell array with a 1 at the correponding index
            index = find(not(cellfun('isempty', indexC))); %extract the index value from the cell array
            curveNames = [];
            defaultColors = [    0    0.4470    0.7410;
                0.8500    0.3250    0.0980;
                0.9290    0.6940    0.1250;
                0.4940    0.1840    0.5560;
                0.4660    0.6740    0.1880;
                0.3010    0.7450    0.9330;
                0.6350    0.0780    0.1840];
            for j=1:1:size(app.phase1Edgesjx2xn,1)
                % Current v Voltage
                plot(app.figureCV,app.voltageArrayjx2xn(app.phase1Edgesjx2xn(j,1,index):app.phase4Edgesjx2xn(j,2,index),index), 10^6.*app.currentArrayjx2xn(app.phase1Edgesjx2xn(j,1,index):app.phase4Edgesjx2xn(j,2,index),index),'LineWidth',2.5)
                curveNames = [curveNames; replace(strcat('Curve_',num2str(j)),'_',' ')];
                hold(app.figureCV,'on')
                % Current & Voltage v Time
                yyaxis(app.figureCV2,'right')
                plot(app.figureCV2,app.timeArrayjx2xn(app.phase1Edgesjx2xn(j,1,index):app.phase4Edgesjx2xn(j,2,index),index),10^6.*app.currentArrayjx2xn(app.phase1Edgesjx2xn(j,1,index):app.phase4Edgesjx2xn(j,2,index),index),'-','LineWidth',2.5,'Color',defaultColors(j,:))
                hold(app.figureCV2,'on')
            end
            
            hold(app.figureCV, 'off')
            xlabel(app.figureCV,'Voltage [V]')
            ylabel(app.figureCV,'Current [\muA]')
            legend(app.figureCV, curveNames)
            indexOfImplantID = strfind(value,'W');
            implantID = value(indexOfImplantID:indexOfImplantID+9);
            electrodeID = value(indexOfImplantID+11:indexOfImplantID+12);
            title(app.figureCV,replace(strcat('Cyclic Voltametry_',implantID,'_',electrodeID),'_',' '))
            
            yyaxis(app.figureCV2,'left')
            hold(app.figureCV2,'off')
            app.figureCV2.YColor = 'black'
            xlabel(app.figureCV2,'Time [s]')
            ylabel(app.figureCV2,'Voltage [V]')
            plot(app.figureCV2,app.timeArrayjx2xn(app.phase1Edgesjx2xn(1,1,index):app.phase4Edgesjx2xn(size(app.phase1Edgesjx2xn,1),2,index)),app.voltageArrayjx2xn(app.phase1Edgesjx2xn(1,1,index):app.phase4Edgesjx2xn(size(app.phase1Edgesjx2xn,1),2,index)),'k','LineWidth',2.5)
            yyaxis(app.figureCV2,'right')
            hold(app.figureCV2,'off')
            app.figureCV2.YColor = 'black'
            ylabel(app.figureCV2,'Current [\muA]')
            legend(app.figureCV2,['Voltage'; curveNames])
            title(app.figureCV2,replace(strcat('Cyclic Voltametry_',implantID,'_',electrodeID),'_',' '))
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ElectrodeAnalyser = uifigure;
            app.ElectrodeAnalyser.Name = 'ElectrodeAnalyser';
            app.Tab = uitabgroup(app.ElectrodeAnalyser);
            app.ImpedanceSpectrometryTab = uitab(app.Tab);
            app.ImpedanceSpectrometryTab.Title = 'Impedance Spectrometry';
            app.SelectedfilesTextAreaLabel = uilabel(app.ImpedanceSpectrometryTab);
            app.SelectedfilesTextAreaLabel.Text = 'Selected files:';
            app.filesListEIS = uitextarea(app.ImpedanceSpectrometryTab);
            app.filesListEIS.Editable = 'off';
            app.BrowseButtonEIS = uibutton(app.ImpedanceSpectrometryTab, 'push');
            app.BrowseButtonEIS.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonEISPushed, true);
            app.BrowseButtonEIS.Text = 'Browse...';
            app.UIModulus = uiaxes(app.ImpedanceSpectrometryTab);
            title(app.UIModulus, 'Impedance Modulus')
            xlabel(app.UIModulus, 'Frequency [Hz]')
            ylabel(app.UIModulus, 'Modulus [kOhm]')
            app.UIPhase = uiaxes(app.ImpedanceSpectrometryTab);
            title(app.UIPhase, 'Phase')
            xlabel(app.UIPhase, 'Frequency [Hz]')
            ylabel(app.UIPhase, 'Phase [deg]')
            app.tableImpedance1khz = uitable(app.ImpedanceSpectrometryTab);
            app.tableImpedance1khz.ColumnName = {'Electrode ID'; 'Frequency [Hz]'; 'Impedance [kOhm]'};
            app.tableImpedance1khz.RowName = {};
            app.ProcessButtonEIS = uibutton(app.ImpedanceSpectrometryTab, 'push');
            app.ProcessButtonEIS.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonEISPushed, true);
            app.ProcessButtonEIS.Enable = 'off';
            app.ProcessButtonEIS.Text = 'Process';
            app.CyclicVoltametryTab = uitab(app.Tab);
            app.CyclicVoltametryTab.Title = 'Cyclic Voltametry';
            app.BrowseButtonCV = uibutton(app.CyclicVoltametryTab, 'push');
            app.BrowseButtonCV.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonCVPushed, true);
            app.BrowseButtonCV.Text = 'Browse...';
            app.figureCV = uiaxes(app.CyclicVoltametryTab);
            title(app.figureCV, 'Current vs Voltage')
            xlabel(app.figureCV, 'Voltage [V]')
            ylabel(app.figureCV, 'Current [muA]')
            app.figureCV2 = uiaxes(app.CyclicVoltametryTab);
            title(app.figureCV2, 'Voltage & Current vs Time')
            xlabel(app.figureCV2, 'Time [s]')
            ylabel(app.figureCV2, 'Voltage [V]')
            app.tableCSC = uitable(app.CyclicVoltametryTab);
            app.tableCSC.ColumnName = {'Electrode ID'; 'Curve #'; 'CSC [mF]'};
            app.tableCSC.RowName = {};
            app.ProcessButtonCV = uibutton(app.CyclicVoltametryTab, 'push');
            app.ProcessButtonCV.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonCVPushed, true);
            app.ProcessButtonCV.Enable = 'off';
            app.ProcessButtonCV.Text = 'Process';
            app.SelectedfilesTextArea_2Label = uilabel(app.CyclicVoltametryTab);
            app.SelectedfilesTextArea_2Label.Text = 'Selected files:';
            app.filesListCV = uitextarea(app.CyclicVoltametryTab);
            app.filesListCV.Editable = 'off';
            app.ShowDropDownLabel = uilabel(app.CyclicVoltametryTab);
            app.ShowDropDownLabel.Text = 'Show:';
            app.filesDropDown = uidropdown(app.CyclicVoltametryTab);
            app.filesDropDown.Items = {};
            app.filesDropDown.ValueChangedFcn = createCallbackFcn(app, @filesDropDownValueChanged, true);
            app.filesDropDown.Enable = 'off';
            app.filesDropDown.Value = {};
            app.VoltageTransientTab = uitab(app.Tab);
            app.VoltageTransientTab.Title = 'Voltage Transient';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Electrode_Analyser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ElectrodeAnalyser)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ElectrodeAnalyser)
        end
    end
end